classdef Triangle_Analysis_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        TriangleAnalysisLabel     matlab.ui.control.Label
        CalculateButton           matlab.ui.control.Button
        xEditFieldLabel           matlab.ui.control.Label
        Ax                        matlab.ui.control.EditField
        xEditField_3Label         matlab.ui.control.Label
        Bx                        matlab.ui.control.EditField
        xEditField_5Label         matlab.ui.control.Label
        Cx                        matlab.ui.control.EditField
        PointALabel               matlab.ui.control.Label
        PointBLabel               matlab.ui.control.Label
        PointCLabel               matlab.ui.control.Label
        yEditFieldLabel           matlab.ui.control.Label
        Ay                        matlab.ui.control.EditField
        yEditField_2Label         matlab.ui.control.Label
        By                        matlab.ui.control.EditField
        yEditField_3Label         matlab.ui.control.Label
        Cy                        matlab.ui.control.EditField
        FunctionTextAreaLabel     matlab.ui.control.Label
        FunctionTextArea          matlab.ui.control.TextArea
        ClearGraphButton          matlab.ui.control.Button
        RightPanel                matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
        ErrorCheckEditFieldLabel  matlab.ui.control.Label
        ErrorTrigger              matlab.ui.control.EditField
        xEditFieldLabel_2         matlab.ui.control.Label
        IncenterX                 matlab.ui.control.EditField
        yEditFieldLabel_2         matlab.ui.control.Label
        IncenterY                 matlab.ui.control.EditField
        IncenterLabel             matlab.ui.control.Label
        RadiusLabel               matlab.ui.control.Label
        IncenterR                 matlab.ui.control.EditField
        CircumcenterLabel         matlab.ui.control.Label
        xEditFieldLabel_3         matlab.ui.control.Label
        CircumcenterX             matlab.ui.control.EditField
        yEditFieldLabel_3         matlab.ui.control.Label
        CircumcenterY             matlab.ui.control.EditField
        RadiusLabel_2             matlab.ui.control.Label
        CircumcenterR             matlab.ui.control.EditField
        CentroidLabel             matlab.ui.control.Label
        OrthocenterLabel          matlab.ui.control.Label
        xEditFieldLabel_4         matlab.ui.control.Label
        CentroidX                 matlab.ui.control.EditField
        xEditFieldLabel_5         matlab.ui.control.Label
        OrthocenterX              matlab.ui.control.EditField
        yEditFieldLabel_4         matlab.ui.control.Label
        CentroidY                 matlab.ui.control.EditField
        yEditFieldLabel_5         matlab.ui.control.Label
        OrthocenterY              matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        
        function [incenter,r] = incent(app,A,B,C)
            % get the incenter of the triangle by given points
            % Find mid points
            BC = norm(B-C);
            AC = norm(A-C);
            BA = norm(B-A);
            incenter = [(BC*A(1)+BA*C(1)+AC*B(1))/(BC+AC+BA),...
                        (BC*A(2)+BA*C(2)+AC*B(2))/(BC+AC+BA)];
            
            s = (BC+AC+BA)/2;
            Area = sqrt(s*(s-BC)*(s-BA)*(s-AC));
            r = Area/s;
            % incenter found
        end

        
        function centroid = cent(app,A,B,C,dec)
        % get the centroid of the triangle by given points
        error = 1; % recheck switch
        % Find mid points
        mid_A = (B(:) + C(:))./2;
        mid_B = (C(:) + A(:))./2;
        mid_C = (B(:) + A(:))./2;
        % form lines joining mid points
        % A line
        coeff = polyfit([A(1), mid_A(1)], [A(2), mid_A(2)], 1);
        a_Aline = coeff(1);
        b_Aline = coeff(2);
        % Aline = a_Aline*x + b_Aline;
        % B line
        coeff = polyfit([B(1), mid_B(1)], [B(2), mid_B(2)], 1);
        a_Bline = coeff(1);
        b_Bline = coeff(2);
        % Bline = a_Bline*x + b_Bline;
        % C line
        coeff = polyfit([C(1), mid_C(1)], [C(2), mid_C(2)], 1);
        a_Cline = coeff(1);
        b_Cline = coeff(2);
        % Cline = a_Cline*x + b_Cline;
        % Aline and Bline intersect
        A_AB = [1, -a_Aline; 1 -a_Bline];
        B_AB = [b_Aline; b_Bline];
        sol1 = A_AB\B_AB;
        % recheck values
        A_BC = [1, -a_Cline; 1 -a_Bline];
        B_BC = [b_Cline; b_Bline];
        sol2 = A_BC\B_BC;
        while error~=0
            if sol1==sol2
                app.ErrorTrigger.Value = 'No error.';
                centroid = [sol1(2),sol1(1)];
                error = 0; 
            elseif dec == 0
                app.ErrorTrigger.Value = 'centroid not found.';
                centroid = [0,0];
                break
            else
                error = 2;
                dec = dec-1;
                sol1 = round(sol1,dec);
                sol2 = round(sol2,dec);
            end
        end
        % centroid found
        end
        
        function [circumcenter,r] = circum(app,A,B,C,dec)
        % get the circumcenter of the triangle by given points
        error = 1; % recheck switch
        % Find mid points
        mid_A = (B(:) + C(:))./2;
        mid_B = (C(:) + A(:))./2;
        mid_C = (B(:) + A(:))./2;
        % Aline is the BC line
        coeff = polyfit([B(1), C(1)], [B(2), C(2)], 1);
        a_Aline = coeff(1);
        b_Aline = coeff(2);
        % Aline = a_Aline*x + b_Aline;
        % Bline is the AC line
        coeff = polyfit([A(1), C(1)], [A(2), C(2)], 1);
        a_Bline = coeff(1);
        b_Bline = coeff(2);
        % Bline = a_Bline*x + b_Bline;
        % Cline is the AB line
        coeff = polyfit([B(1), A(1)], [B(2), A(2)], 1);
        a_Cline = coeff(1);
        b_Cline = coeff(2);
        % Cline = a_Cline*x + b_Cline;
        
        % Find circumcenter
        % perpendicular to Aline from midpoint BC
        ap_Aline = round(-1/a_Aline,dec);
        bp_Aline = (-ap_Aline*mid_A(1)+mid_A(2));
        % p_Aline = ap_Aline*x + bp_Aline;
        % perpendicular to Bline from midpoint AC
        ap_Bline = round(-1/a_Bline,dec);
        bp_Bline = (-ap_Bline*mid_B(1)+mid_B(2));
        % p_Bline = ap_Bline*x + bp_Bline;
        % perpendicular to Cline from midpoint AB
        ap_Cline = round(-1/a_Cline,dec);
        bp_Cline = (-ap_Cline*mid_C(1)+mid_C(2));
        % p_Cline = ap_Cline*x + bp_Cline;
        % Aline and Bline intersect
        A_AB = [1, -ap_Aline; 1 -ap_Bline];
        B_AB = [bp_Aline; bp_Bline];
        sol1 = A_AB\B_AB;
        % recheck values
        A_BC = [1, -ap_Cline; 1 -ap_Bline];
        B_BC = [bp_Cline; bp_Bline];
        sol2 = A_BC\B_BC;
        
        while error~=0
            if sol1==sol2
                app.ErrorTrigger.Value = 'No error.';
                circumcenter = [sol1(2),sol1(1)];
                error = 0;
                r = norm(circumcenter-C);
            elseif dec == 0
                app.ErrorTrigger.Value = 'circumcenter not found.';
                circumcenter = [0,0];
                r = 0;
                break
            else
                error = 2;
                dec = dec-1;
                sol1 = round(sol1,dec);
                sol2 = round(sol2,dec);
            end
        end
        % incenter found
        end
        
        function orthocenter = ortho(app,A,B,C,dec)
        % get the orthocenter of the triangle by given points
        error = 1; % recheck switch
        % Aline is the BC line
        coeff = polyfit([B(1), C(1)], [B(2), C(2)], 1);
        a_Aline = coeff(1);
        b_Aline = coeff(2);
        % Aline = a_Aline*x + b_Aline;
        % Bline is the AC line
        coeff = polyfit([A(1), C(1)], [A(2), C(2)], 1);
        a_Bline = coeff(1);
        b_Bline = coeff(2);
        % Bline = a_Bline*x + b_Bline;
        % Cline is the AB line
        coeff = polyfit([B(1), A(1)], [B(2), A(2)], 1);
        a_Cline = coeff(1);
        b_Cline = coeff(2);
        % Cline = a_Cline*x + b_Cline;
        
        
        % Find orthocenter
        % perpendicular to Aline
        ap_Aline = round(-1/a_Aline,dec);
        bp_Aline = (-ap_Aline*A(1)+A(2));
        % p_Aline = ap_Aline*x + bp_Aline;
        % perpendicular to Bline
        ap_Bline = round(-1/a_Bline,dec);
        bp_Bline = (-ap_Bline*B(1)+B(2));
        % p_Bline = ap_Bline*x + bp_Bline;
        % perpendicular to Cline
        ap_Cline = round(-1/a_Cline,dec);
        bp_Cline = (-ap_Cline*C(1)+C(2));
        % p_Cline = ap_Cline*x + bp_Cline;
        % Aline and Bline intersect
        A_AB = [1, -ap_Aline; 1 -ap_Bline];
        B_AB = [bp_Aline; bp_Bline];
        sol1 = A_AB\B_AB;
        % recheck values
        A_BC = [1, -ap_Cline; 1 -ap_Bline];
        B_BC = [bp_Cline; bp_Bline];
        sol2 = A_BC\B_BC;
        while error~=0
            if sol1==sol2
                app.ErrorTrigger.Value = 'No error.';
                orthocenter = [sol1(2),sol1(1)];
                error = 0;
            elseif dec == 0
                app.ErrorTrigger.Value = 'orthocenter not found.';
                orthocenter = [0,0];
                break
            else
                error = 2;
                dec = dec-1;
                sol1 = round(sol1,dec);
                sol2 = round(sol2,dec);
            end
        end
        % orthocenter found
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            dec = 6; % decimal tolerance
            % vertices of triangle
            A = [str2double(app.Ax.Value), str2double(app.Ay.Value)];
            B = [str2double(app.Bx.Value), str2double(app.By.Value)];
            if A(1)==B(1) && A(2)==B(2)
                app.ErrorTrigger.Value = 'Invalid Vertices! Please Re-enter.';
                return
            end
            C = [str2double(app.Cx.Value), str2double(app.Cy.Value)];
            if C(1)==B(1) && C(2)==B(2)
                app.ErrorTrigger.Value = 'Invalid Vertices! Please Re-enter.';
                return
            end
            if A(1)==C(1) && A(2)==C(2)
                app.ErrorTrigger.Value = 'Invalid Vertices! Please Re-enter.';
                return
            end
            if A(1)==0
                A(1)=1e-5;
            end
            if A(2)==0
                A(2)=1e-5;
            end
            
            if B(1)==0
                B(1)=1e-5;
            end
            if B(2)==0
                B(2)=1e-5;
            end
            
            if C(1)==0
                C(1)=1e-5;
            end
            if C(2)==0
                C(2)=1e-5;
            end
            % plot triangle
            ABC = cat(1,A,B,C,A);
            hold(app.UIAxes,'on')
            axis(app.UIAxes,"square")
            axis(app.UIAxes,"equal")
            plot(app.UIAxes, ABC(:,1),ABC(:,2),'k','LineWidth',2,'HandleVisibility','off')
            
            % centroid, in, circum and ortho-center calculation
            centroid = cent(app,A,B,C,dec);
            app.CentroidX.Value = num2str(centroid(1),'%.4f');
            app.CentroidY.Value = num2str(centroid(2),'%.4f');
            plot(app.UIAxes,centroid(1),centroid(2),'*k','LineWidth',2,'MarkerSize',10)
            plot(app.UIAxes,[A(1),centroid(1)],[A(2),centroid(2)],'--b','HandleVisibility','off')
            plot(app.UIAxes,[B(1),centroid(1)],[B(2),centroid(2)],'--g','HandleVisibility','off')
            plot(app.UIAxes,[C(1),centroid(1)],[C(2),centroid(2)],'--m','HandleVisibility','off')
            
            [incenter,r] = incent(app,A,B,C);
            plot(app.UIAxes, incenter(1),incenter(2),'or','LineWidth',2,'MarkerSize',10)
            rectangle(app.UIAxes,'Position', [incenter(1)-r,incenter(2)-r,2*r,2*r],'Curvature', [1 1],'EdgeColor','r')
            plot(app.UIAxes, [A(1),incenter(1)],[A(2),incenter(2)],'-b','HandleVisibility','off')
            plot(app.UIAxes, [B(1),incenter(1)],[B(2),incenter(2)],'-g','HandleVisibility','off')
            plot(app.UIAxes, [C(1),incenter(1)],[C(2),incenter(2)],'-m','HandleVisibility','off')
            app.IncenterX.Value = num2str(incenter(1),'%.4f');
            app.IncenterY.Value = num2str(incenter(2),'%.4f');
            app.IncenterR.Value = num2str(r,'%.4f');

            [circumcenter,r] = circum(app,A,B,C,dec);
            plot(app.UIAxes,circumcenter(1),circumcenter(2),'hg','LineWidth',2,'MarkerSize',10)
            rectangle(app.UIAxes,'Position', [circumcenter(1)-r,circumcenter(2)-r,2*r,2*r],'Curvature', [1 1],'EdgeColor','g')
            plot(app.UIAxes,[A(1),circumcenter(1)],[A(2),circumcenter(2)],':b','HandleVisibility','off')
            plot(app.UIAxes,[B(1),circumcenter(1)],[B(2),circumcenter(2)],':g','HandleVisibility','off')
            plot(app.UIAxes,[C(1),circumcenter(1)],[C(2),circumcenter(2)],':m','HandleVisibility','off')
            app.CircumcenterX.Value = num2str(circumcenter(1),'%.4f');
            app.CircumcenterY.Value = num2str(circumcenter(2),'%.4f');
            app.CircumcenterR.Value = num2str(r,'%.4f');

            orthocenter = ortho(app,A,B,C,dec);
            plot(app.UIAxes,orthocenter(1),orthocenter(2),'sb','LineWidth',2,'MarkerSize',10)
            plot(app.UIAxes,[A(1),orthocenter(1)],[A(2),orthocenter(2)],'-.b')
            plot(app.UIAxes,[B(1),orthocenter(1)],[B(2),orthocenter(2)],'-.g')
            plot(app.UIAxes,[C(1),orthocenter(1)],[C(2),orthocenter(2)],'-.m')
            app.OrthocenterX.Value = num2str(orthocenter(1),'%.4f');
            app.OrthocenterY.Value = num2str(orthocenter(2),'%.4f');

            % finding the perfect boundery for plotting
            XY_min = circumcenter-r;
            XY_max = circumcenter+r;
            if XY_min(1)>orthocenter(1)
                XY_min(1) = orthocenter(1);
            end
            if XY_min(2)>orthocenter(2)
                XY_min(2) = orthocenter(2);
            end
            if XY_max(1)<orthocenter(1)
                XY_max(1) = orthocenter(1);
            end
            if XY_max(2)<orthocenter(2)
                XY_max(2) = orthocenter(2);
            end
            % plotting the extention line for each sides of triangle
            x = linspace(XY_min(1)*1.06,XY_max(1)*1.06); % operation range
            % Aline is the BC line
            coeff = polyfit([B(1), C(1)], [B(2), C(2)], 1);
            a_Aline = coeff(1);
            b_Aline = coeff(2);
            Aline = a_Aline*x + b_Aline;
            % Bline is the AC line
            coeff = polyfit([A(1), C(1)], [A(2), C(2)], 1);
            a_Bline = coeff(1);
            b_Bline = coeff(2);
            Bline = a_Bline*x + b_Bline;
            % Cline is the AB line
            coeff = polyfit([B(1), A(1)], [B(2), A(2)], 1);
            a_Cline = coeff(1);
            b_Cline = coeff(2);
            Cline = a_Cline*x + b_Cline;
            plot(app.UIAxes,x,Aline,':b',x,Bline,':g',x,Cline,':m')
            if XY_max(1)==XY_min(1)
                app.ErrorTrigger.Value = 'Unknown Error. Shuffle A,B,C.';
                return
            end
            xlim(app.UIAxes,[XY_min(1)*1.06 XY_max(1)*1.06])
            ylim(app.UIAxes,[XY_min(2)*1.06 XY_max(2)*1.06])
            legend(app.UIAxes,'Centroid','Incenter','Circumcenter','Orthocenter')
            hold(app.UIAxes,'off')
            % end of triangle points finding code
        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            cla(app.UIAxes,'reset');
            app.UIAxes.XGrid = 'on';
            app.UIAxes.YGrid = 'on';
            app.UIAxes.Box = 'on';
            axis(app.UIAxes,"square")
            axis(app.UIAxes,"equal")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TriangleAnalysisLabel = uilabel(app.LeftPanel);
            app.TriangleAnalysisLabel.Text = 'Triangle Analysis';
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.Ax = uieditfield(app.LeftPanel, 'text');
            app.xEditField_3Label = uilabel(app.LeftPanel);
            app.xEditField_3Label.Text = 'x';
            app.Bx = uieditfield(app.LeftPanel, 'text');
            app.xEditField_5Label = uilabel(app.LeftPanel);
            app.xEditField_5Label.Text = 'x';
            app.Cx = uieditfield(app.LeftPanel, 'text');
            app.PointALabel = uilabel(app.LeftPanel);
            app.PointALabel.Text = 'Point A';
            app.PointBLabel = uilabel(app.LeftPanel);
            app.PointBLabel.Text = 'Point B';
            app.PointCLabel = uilabel(app.LeftPanel);
            app.PointCLabel.Text = 'Point C';
            app.yEditFieldLabel = uilabel(app.LeftPanel);
            app.yEditFieldLabel.Text = 'y';
            app.Ay = uieditfield(app.LeftPanel, 'text');
            app.yEditField_2Label = uilabel(app.LeftPanel);
            app.yEditField_2Label.Text = 'y';
            app.By = uieditfield(app.LeftPanel, 'text');
            app.yEditField_3Label = uilabel(app.LeftPanel);
            app.yEditField_3Label.Text = 'y';
            app.Cy = uieditfield(app.LeftPanel, 'text');
            app.FunctionTextAreaLabel = uilabel(app.LeftPanel);
            app.FunctionTextAreaLabel.Text = 'Function';
            app.FunctionTextArea = uitextarea(app.LeftPanel);
            app.FunctionTextArea.Editable = 'off';
            app.FunctionTextArea.Value = {'Calculates centroid, incenter, circumcenter and orthocenter of a triangle. User needs to input the triangle interms of the co-ordinates of the vertices in the X-Y plane.'};
            app.ClearGraphButton = uibutton(app.LeftPanel, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Graphical View')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ErrorCheckEditFieldLabel = uilabel(app.RightPanel);
            app.ErrorCheckEditFieldLabel.Text = 'Error Check';
            app.ErrorTrigger = uieditfield(app.RightPanel, 'text');
            app.ErrorTrigger.Editable = 'off';
            app.xEditFieldLabel_2 = uilabel(app.RightPanel);
            app.xEditFieldLabel_2.Text = 'x';
            app.IncenterX = uieditfield(app.RightPanel, 'text');
            app.yEditFieldLabel_2 = uilabel(app.RightPanel);
            app.yEditFieldLabel_2.Text = 'y';
            app.IncenterY = uieditfield(app.RightPanel, 'text');
            app.IncenterLabel = uilabel(app.RightPanel);
            app.IncenterLabel.Text = 'Incenter';
            app.RadiusLabel = uilabel(app.RightPanel);
            app.RadiusLabel.Text = 'Radius';
            app.IncenterR = uieditfield(app.RightPanel, 'text');
            app.CircumcenterLabel = uilabel(app.RightPanel);
            app.CircumcenterLabel.Text = 'Circumcenter';
            app.xEditFieldLabel_3 = uilabel(app.RightPanel);
            app.xEditFieldLabel_3.Text = 'x';
            app.CircumcenterX = uieditfield(app.RightPanel, 'text');
            app.yEditFieldLabel_3 = uilabel(app.RightPanel);
            app.yEditFieldLabel_3.Text = 'y';
            app.CircumcenterY = uieditfield(app.RightPanel, 'text');
            app.RadiusLabel_2 = uilabel(app.RightPanel);
            app.RadiusLabel_2.Text = 'Radius';
            app.CircumcenterR = uieditfield(app.RightPanel, 'text');
            app.CentroidLabel = uilabel(app.RightPanel);
            app.CentroidLabel.Text = 'Centroid';
            app.OrthocenterLabel = uilabel(app.RightPanel);
            app.OrthocenterLabel.Text = 'Orthocenter';
            app.xEditFieldLabel_4 = uilabel(app.RightPanel);
            app.xEditFieldLabel_4.Text = 'x';
            app.CentroidX = uieditfield(app.RightPanel, 'text');
            app.xEditFieldLabel_5 = uilabel(app.RightPanel);
            app.xEditFieldLabel_5.Text = 'x';
            app.OrthocenterX = uieditfield(app.RightPanel, 'text');
            app.yEditFieldLabel_4 = uilabel(app.RightPanel);
            app.yEditFieldLabel_4.Text = 'y';
            app.CentroidY = uieditfield(app.RightPanel, 'text');
            app.yEditFieldLabel_5 = uilabel(app.RightPanel);
            app.yEditFieldLabel_5.Text = 'y';
            app.OrthocenterY = uieditfield(app.RightPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Triangle_Analysis_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end